classdef CurveFitting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CurveFitingPanel             matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        ConfigPanel                  matlab.ui.container.Panel
        PredictPanel                 matlab.ui.container.Panel
        PredictButton                matlab.ui.control.Button
        ZEditField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        DatadimensionDropDown        matlab.ui.control.DropDown
        DatadimensionDropDownLabel   matlab.ui.control.Label
        TrainEnvironmentDropDown     matlab.ui.control.DropDown
        TrainEnvironmentLabel        matlab.ui.control.Label
        PlotdatapointsButton         matlab.ui.control.Button
        GenerateNeuralNetButton      matlab.ui.control.Button
        ImportdataButton             matlab.ui.control.Button
        TrainnettofitButton          matlab.ui.control.Button
        NetConfigPanel               matlab.ui.container.Panel
        NeuronsnumberEditField       matlab.ui.control.NumericEditField
        NeuronsnumberEditFieldLabel  matlab.ui.control.Label
        NetLayersEditField           matlab.ui.control.NumericEditField
        NetLayersEditFieldLabel      matlab.ui.control.Label
        ActiveFunctionDropDown       matlab.ui.control.DropDown
        ActiveFunctionDropDownLabel  matlab.ui.control.Label
        TrainConfigPanel             matlab.ui.container.Panel
        BatchSizeEditField           matlab.ui.control.NumericEditField
        BatchSizeEditFieldLabel      matlab.ui.control.Label
        EpochEditField               matlab.ui.control.NumericEditField
        EpochEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        DataPath % Description
        DataName % Description
        dataX % Description
        dataY % Description
        dataZ % Description
        dimension % Description
        layers % Description
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
        end

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            [app.DataName,app.DataPath] = uigetfile('*.*','Please select the data source file');
            data = readtable(strcat(app.DataPath,app.DataName));
            data = table2array(data);
            if(app.DatadimensionDropDown.Value=="2-D")
                app.dataX = data(1,:);
                app.dataY = data(2,:);
                app.dimension = 2;
            else
                app.dataX = data(1,:);
                app.dataY = data(2,:);
                app.dataZ = data(3,:);
                app.dimension = 3;
            end
            cla(app.UIAxes, 'reset');
        end

        % Button pushed function: PlotdatapointsButton
        function PlotdatapointsButtonPushed(app, event)
           if (app.DatadimensionDropDown.Value=="2-D")
               scatter(app.UIAxes, app.dataX, app.dataY);
           else
               scatter3(app.UIAxes, app.dataX, app.dataY, app.dataZ);
           end
        end

        % Button pushed function: GenerateNeuralNetButton
        function GenerateNeuralNetButtonPushed(app, event)
            if(app.DatadimensionDropDown.Value=="2-D")
                app.dimension = 2;
            else
                app.dimension = 3;
            end
            layer_num = app.NetLayersEditField.Value;
            activeFunc = app.ActiveFunctionDropDown.Value;
            neurons = app.NeuronsnumberEditField.Value;
            app.layers = CurveFittingModel(app.dimension, layer_num, activeFunc, neurons);
            figure,plot(layerGraph(app.layers));
        end

        % Button pushed function: TrainnettofitButton
        function TrainnettofitButtonPushed(app, event)
            if app.dimension == 2
                dataInput = app.dataX;
                dataOutput = app.dataY;
            else
                dataInput = [app.dataX;app.dataY];
                dataOutput = app.dataZ;
            end
            fit_Y = CurveFittingTrain(app.layers,app.EpochEditField.Value,app.BatchSizeEditField.Value,dataInput,dataOutput);
            hold(app.UIAxes, 'on');
            if app.dimension == 2
                plot(app.UIAxes, app.dataX, fit_Y);
            else
                plot3(app.UIAxes, app.dataX, app.dataY, fit_Y);
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if app.dimension == 2
                x = app.XEditField.Value;
                y = CurveFittingPredict(x);
                app.YEditField.Value = double(y);
            else
                x = app.XEditField.Value;
                y = app.YEditField.Value;
                app.ZEditField.Value = double(CurveFittingPredict([x y]));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfigPanel = uipanel(app.UIFigure);
            app.ConfigPanel.Title = 'Config';
            app.TrainConfigPanel = uipanel(app.ConfigPanel);
            app.TrainConfigPanel.Title = 'Train Config';
            app.EpochEditFieldLabel = uilabel(app.TrainConfigPanel);
            app.EpochEditFieldLabel.Text = 'Epoch';
            app.EpochEditField = uieditfield(app.TrainConfigPanel, 'numeric');
            app.BatchSizeEditFieldLabel = uilabel(app.TrainConfigPanel);
            app.BatchSizeEditFieldLabel.Text = 'Batch Size';
            app.BatchSizeEditField = uieditfield(app.TrainConfigPanel, 'numeric');
            app.NetConfigPanel = uipanel(app.ConfigPanel);
            app.NetConfigPanel.Title = 'Net Config';
            app.ActiveFunctionDropDownLabel = uilabel(app.NetConfigPanel);
            app.ActiveFunctionDropDownLabel.Text = 'Active Function';
            app.ActiveFunctionDropDown = uidropdown(app.NetConfigPanel);
            app.ActiveFunctionDropDown.Items = {'Relu', 'LeakyReulu', 'ClippedRelu', 'Tanh', 'Elu', 'Swish'};
            app.ActiveFunctionDropDown.Value = 'Relu';
            app.NetLayersEditFieldLabel = uilabel(app.NetConfigPanel);
            app.NetLayersEditFieldLabel.Text = 'Net Layers';
            app.NetLayersEditField = uieditfield(app.NetConfigPanel, 'numeric');
            app.NeuronsnumberEditFieldLabel = uilabel(app.NetConfigPanel);
            app.NeuronsnumberEditFieldLabel.Text = 'Neurons number';
            app.NeuronsnumberEditField = uieditfield(app.NetConfigPanel, 'numeric');
            app.TrainnettofitButton = uibutton(app.ConfigPanel, 'push');
            app.TrainnettofitButton.ButtonPushedFcn = createCallbackFcn(app, @TrainnettofitButtonPushed, true);
            app.TrainnettofitButton.Text = 'Train net to fit';
            app.ImportdataButton = uibutton(app.ConfigPanel, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = {'Import data'; ''};
            app.GenerateNeuralNetButton = uibutton(app.ConfigPanel, 'push');
            app.GenerateNeuralNetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNeuralNetButtonPushed, true);
            app.GenerateNeuralNetButton.Text = 'Generate Neural Net';
            app.PlotdatapointsButton = uibutton(app.ConfigPanel, 'push');
            app.PlotdatapointsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdatapointsButtonPushed, true);
            app.PlotdatapointsButton.Text = 'Plot data points';
            app.TrainEnvironmentLabel = uilabel(app.ConfigPanel);
            app.TrainEnvironmentLabel.Text = {'Train '; 'Environment'};
            app.TrainEnvironmentDropDown = uidropdown(app.ConfigPanel);
            app.TrainEnvironmentDropDown.Items = {'CPU', 'GPU'};
            app.TrainEnvironmentDropDown.Value = 'CPU';
            app.DatadimensionDropDownLabel = uilabel(app.ConfigPanel);
            app.DatadimensionDropDownLabel.Text = 'Data dimension';
            app.DatadimensionDropDown = uidropdown(app.ConfigPanel);
            app.DatadimensionDropDown.Items = {'2-D', '3-D'};
            app.DatadimensionDropDown.Value = '2-D';
            app.PredictPanel = uipanel(app.ConfigPanel);
            app.PredictPanel.Title = 'Predict';
            app.XEditFieldLabel = uilabel(app.PredictPanel);
            app.XEditFieldLabel.Text = 'X ';
            app.XEditField = uieditfield(app.PredictPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.PredictPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PredictPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.PredictPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.PredictPanel, 'numeric');
            app.PredictButton = uibutton(app.PredictPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.CurveFitingPanel = uipanel(app.UIFigure);
            app.CurveFitingPanel.Title = 'Curve Fiting';
            app.UIAxes = uiaxes(app.CurveFitingPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurveFitting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end