classdef DigitRecognitionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ShowimageprocessdetailsCheckBox  matlab.ui.control.CheckBox
        ProbabilityGaugeLabel   matlab.ui.control.Label
        RecognitionResultLabel  matlab.ui.control.Label
        ResultLabel             matlab.ui.control.Label
        ProbabilityGauge        matlab.ui.control.SemicircularGauge
        RecognitionButton       matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        LoadImageButton         matlab.ui.control.Button
        ModeDropDown            matlab.ui.control.DropDown
        ModeDropDownLabel       matlab.ui.control.Label
        ClearBoardButton        matlab.ui.control.Button
        DigitRecognitionLabel   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        x % Description
        y % Description
        draw_enable % Description
        ImagePath='none' % Description
        ImageName % Description
        TestImage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            set(app.UIAxes,'XLim',[0,1],'YLim',[0,1]);
            app.CallingApp = mainApp;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)

            pos = app.UIAxes.CurrentPoint;
            if pos(1,1)<=1.0 && pos(1,1)>=0.0 && pos(1,2)<=1.0 && pos(1,2)>=0.0
                app.draw_enable = 1;
                app.x(1) = pos(1,1);
                app.y(1) = pos(1,2);
                hold(app.UIAxes, 'on');
            end

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.draw_enable
               point = app.UIAxes.CurrentPoint;
               app.x(2) = point(1,1);
               app.y(2) = point(1,2);
               plot_x = linspace(app.x(1),app.x(2),200);
               plot_y = linspace(app.y(1),app.y(2),200);
               scatter(app.UIAxes,plot_x,plot_y,500,'k','filled');
               app.x(1) = app.x(2);
               app.y(1) = app.y(2);
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.draw_enable = 0;
            
        end

        % Button pushed function: ClearBoardButton
        function ClearBoardButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            app.ImageName = "";
            app.ImagePath = "";
            [app.ImageName,app.ImagePath] = uigetfile('*.*','Please select the image');
            app.Image.ImageSource="";
            app.Image.ImageSource = strcat(app.ImagePath,app.ImageName);
        end

        % Button pushed function: RecognitionButton
        function RecognitionButtonPushed(app, event)
            if app.ModeDropDown.Value == "Handwriting"
                h = get(app.UIAxes,'children');
                f = figure('Visible','off');
                ax = axes;
                set(ax,'ytick',[]);
                set(ax,'xtick',[]);
                axis(ax,'off');
                set(ax,'XLim',[0,1],'YLim',[0,1]);
                copyobj(h,ax);
                saveas(f,'temp/DigitRecognitionTest.jpg');
                close(f);
                app.TestImage = imread('./temp/DigitRecognitionTest.jpg');
                app.TestImage = double(app.TestImage);
                app.TestImage = app.TestImage./255;
            else
                if app.ImagePath == "none"
                    msgbox('Please load image first!');
                else
                    app.TestImage = imread(strcat(app.ImagePath,app.ImageName));
                    app.TestImage = double(app.TestImage);
                    app.TestImage = app.TestImage./255;
                end
            end
            [pre,score]=DigitRecognitionPredict(app.TestImage,app.ShowimageprocessdetailsCheckBox.Value);
            app.RecognitionResultLabel.Text = num2str(pre);
            app.ProbabilityGauge.Value = score;
            app.ProbabilityGaugeLabel.Text = strcat("Probability:", num2str(score,'%.2f'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Handwriting Board')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.DigitRecognitionLabel = uilabel(app.UIFigure);
            app.DigitRecognitionLabel.Text = 'Digit Recognition';
            app.ClearBoardButton = uibutton(app.UIFigure, 'push');
            app.ClearBoardButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBoardButtonPushed, true);
            app.ClearBoardButton.Text = 'Clear Board';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'Handwriting', 'Load Image'};
            app.ModeDropDown.Value = 'Handwriting';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.Image = uiimage(app.UIFigure);
            app.RecognitionButton = uibutton(app.UIFigure, 'push');
            app.RecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @RecognitionButtonPushed, true);
            app.RecognitionButton.Text = 'Recognition';
            app.ProbabilityGauge = uigauge(app.UIFigure, 'semicircular');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result :';
            app.RecognitionResultLabel = uilabel(app.UIFigure);
            app.RecognitionResultLabel.Text = 'None';
            app.ProbabilityGaugeLabel = uilabel(app.UIFigure);
            app.ProbabilityGaugeLabel.Text = 'Probability:0.00';
            app.ShowimageprocessdetailsCheckBox = uicheckbox(app.UIFigure);
            app.ShowimageprocessdetailsCheckBox.Text = 'Show image process details';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DigitRecognitionGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end